classdef FraunhoferDiffraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FraunhoferDiffractionUIFigure  matlab.ui.Figure
        FRAUNHOFERDIFFRACTIONATAPERTURESLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        Tab_Circular                   matlab.ui.container.Tab
        Label_Radius                   matlab.ui.control.Label
        RadiusSliderLabel              matlab.ui.control.Label
        Slider_Radius                  matlab.ui.control.Slider
        Label_Radius_unit              matlab.ui.control.Label
        Label_microns                  matlab.ui.control.Label
        Label_D                        matlab.ui.control.Label
        Tab_Rectangular                matlab.ui.container.Tab
        HeightSliderLabel              matlab.ui.control.Label
        Slider_Height                  matlab.ui.control.Slider
        WidthSliderLabel               matlab.ui.control.Label
        Slider_Width                   matlab.ui.control.Slider
        Label_Height                   matlab.ui.control.Label
        Label_Width                    matlab.ui.control.Label
        Tab_DoubleSlit                 matlab.ui.container.Tab
        Label_DSHeight                 matlab.ui.control.Label
        Label_DSWidth                  matlab.ui.control.Label
        Label_DSSeparation             matlab.ui.control.Label
        HeightSliderLabel_2            matlab.ui.control.Label
        Slider_DSHeight                matlab.ui.control.Slider
        WidthSliderLabel_2             matlab.ui.control.Label
        Slider_DSWidth                 matlab.ui.control.Slider
        SeparationLabel                matlab.ui.control.Label
        Slider_DSSeparation            matlab.ui.control.Slider
        AperturetypeLabel              matlab.ui.control.Label
        nmSliderLabel                  matlab.ui.control.Label
        Slider_Lambda                  matlab.ui.control.Slider
        zcmSliderLabel                 matlab.ui.control.Label
        Slider_Z                       matlab.ui.control.Slider
        Label_Lambda                   matlab.ui.control.Label
        Label_Z                        matlab.ui.control.Label
        InpixelsLabel                  matlab.ui.control.Label
        Panel_Aperture                 matlab.ui.container.Panel
        UIAxes_Aperture                matlab.ui.control.UIAxes
        Panel_Diffraction              matlab.ui.container.Panel
        UIAxes_Diffraction             matlab.ui.control.UIAxes
        Panel_DiffractionCS            matlab.ui.container.Panel
        UIAxes_DiffractionCS           matlab.ui.control.UIAxes
        NumberofsamplingpointsDropDownLabel  matlab.ui.control.Label
        DropDown_SP                    matlab.ui.control.DropDown
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Diameterapproximationconsideringapertureplane1x1cmLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        vid;
        R;
        SP;
        FA;
        X;
        Y;
    end
    
    methods (Access = private)
        
        %   APERTURE FUNCTION
            function aperture(app)
                app.R=zeros(app.SP);                                                % SP = number of sampling points
                rU=round(app.SP/2);
                
                if app.TabGroup.SelectedTab.Title == app.Tab_Circular.Title
                    Ro=str2double(app.Label_Radius.Text);
                    for n=1:app.SP
                        for m=1:app.SP
                            if(n-rU)^2+(m-rU)^2<=Ro^2
                                app.R(n,m)=1;
                            end
                        end
                    end
                elseif app.TabGroup.SelectedTab.Title == app.Tab_Rectangular.Title
                    M=str2double(app.Label_Height.Text);
                    N=str2double(app.Label_Width.Text);
                    r = ones(M,N);                                                  % assign a matrix MxN of ones.
                    h = M/2;
                    w = N/2;
                    A=ceil(rU-h+1);
                    B=ceil(rU+h);
                    C=ceil(rU-w+1);
                    D=ceil(rU+w);
                    app.R(A:B, C:D) = r;
                elseif app.TabGroup.SelectedTab.Title == app.Tab_DoubleSlit.Title
                    M = str2double(app.Label_DSHeight.Text);
                    N = str2double(app.Label_DSWidth.Text);
                    S = str2double(app.Label_DSSeparation.Text);
                    r = ones(M,N);                                                  % assign a matrix MxN of ones.
                    h = M/2;
                    w = N/2;
                    s = S/2;
                    A=ceil(rU-h+1);
                    B=ceil(rU+h);
                    C1=ceil(rU-w+1-s);
                    D1=ceil(rU+w-s);
                    C2=ceil(rU-w+1+s);
                    D2=ceil(rU+w+s);
                    app.R(A:B, C1:D1) = r;
                    app.R(A:B, C2:D2) = r;
                else
                        app.R=app.R;
                end            
                % Aperture Scaling
                d=1;
                L = 1;                                                              % L: Length of display area.
                for k=1:app.SP
                    app.X(k) = ((k-1)/(app.SP-1)/d)-(L/(2*d));
                    app.Y(k) = ((k-1)/(app.SP-1)/d)-(L/(2*d));
                end
                imshow(255*app.R,'parent',app.UIAxes_Aperture);      
            end
        
        %   DIFFRACTION FUNCTION
            function diffraction(app)
            
                FT_R = (1/app.SP)^2*fft2(256*app.R);                                % FT_R: Fourier Transform of R
                FT_R = fftshift(FT_R);      
                
                z = str2double(app.Label_Z.Text);                                   % distance z: 20 cms
                lambda = str2double(app.Label_Lambda.Text);                         % wavelength: 630 nm
                Lcm = lambda*10^-7;                                                 % wavelength: lambda in centimeters
                sigma = pi/(z*Lcm);
                
                for x = 1:app.SP
                    for y = 1:app.SP
                        h(x,y) = j*(sigma/pi)*exp(0*(app.X(x).^2+app.Y(y).^2))*exp(j*sigma*(app.X(x).^2+app.Y(y).^2));
                    end
                end
        
                H  = (1/app.SP)^2*fft2(256*h);
                H  = fftshift(H);
                HR = FT_R.*H;
                hr = ifft2(HR);
                hr = (app.SP^2)*hr;
                hr = fftshift(hr);        
                
                imshow(255*(abs(hr)).^2/(max(max(abs(hr))))^2,'parent',app.UIAxes_Diffraction);

                rU=round(app.SP/2);
                app.UIAxes_DiffractionCS.YLim = ([0 max(max(abs(hr)))*1.1]);
                app.UIAxes_DiffractionCS.XLim = ([app.FA app.SP-app.FA]);
                
                diffCS = abs(hr(rU,:));
                plot(diffCS,'parent',app.UIAxes_DiffractionCS);
                app.UIAxes_DiffractionCS.XTick=([app.FA app.SP-app.FA]);
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all; clc;
            app.SP = str2double(app.DropDown_SP.Value);
            app.UIAxes_Aperture.XLim = ([0 app.SP]);
            app.UIAxes_Aperture.YLim = ([0 app.SP]);
            app.FA = app.SP*0.05;
            app.UIAxes_Diffraction.XLim = ([app.FA app.SP-app.FA]);
            app.UIAxes_Diffraction.YLim = ([app.FA app.SP-app.FA]);
            
            aperture(app);
            diffraction(app);
        end

        % Value changing function: Slider_Radius
        function Slider_RadiusValueChanging(app, event)
            changingValue = round(event.Value);
            app.Label_Radius.Text = num2str(changingValue);
            app.Label_Radius_unit.Text = num2str(round(2*changingValue*10000/app.SP));
            aperture(app);
            diffraction(app);
        end

        % Value changing function: Slider_Height
        function Slider_HeightValueChanging(app, event)
            changingValue = round(event.Value);
            app.Label_Height.Text = num2str(changingValue);
            aperture(app);
            diffraction(app);
        end

        % Value changing function: Slider_Width
        function Slider_WidthValueChanging(app, event)
            changingValue = round(event.Value);
            app.Label_Width.Text = num2str(changingValue);
            aperture(app);
            diffraction(app);
        end

        % Value changing function: Slider_Lambda
        function Slider_LambdaValueChanging(app, event)
            changingValue = round(event.Value);
            app.Label_Lambda.Text = num2str(changingValue);
            diffraction(app);
        end

        % Value changing function: Slider_Z
        function Slider_ZValueChanging(app, event)
            changingValue = round(event.Value);
            app.Label_Z.Text = num2str(changingValue);
            diffraction(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            aperture(app);
            diffraction(app);
        end

        % Value changed function: DropDown_SP
        function DropDown_SPValueChanged(app, event)
            app.SP = str2double(app.DropDown_SP.Value);
            app.UIAxes_Aperture.XLim = ([0 app.SP]);
            app.UIAxes_Aperture.YLim = ([0 app.SP]);
            app.UIAxes_Diffraction.XLim = ([app.FA app.SP-app.FA]);
            app.UIAxes_Diffraction.YLim = ([app.FA app.SP-app.FA]);    
            app.Label_Radius_unit.Text = num2str(round(2*str2double(app.Label_Radius.Text)*10000/app.SP));
            
            aperture(app);
            diffraction(app);           
            
        end

        % Value changed function: Slider_Height
        function Slider_HeightValueChanged(app, event)
%             diffraction(app);
        end

        % Value changed function: Slider_Width
        function Slider_WidthValueChanged(app, event)
%             diffraction(app);
        end

        % Value changed function: Slider_Radius
        function Slider_RadiusValueChanged(app, event)
%             diffraction(app);
        end

        % Value changed function: Slider_Lambda
        function Slider_LambdaValueChanged(app, event)
%             diffraction(app);
        end

        % Value changed function: Slider_Z
        function Slider_ZValueChanged(app, event)
%             diffraction(app);
        end

        % Value changing function: Slider_DSHeight
        function Slider_DSHeightValueChanging(app, event)
            changingValue = round(event.Value);
            app.Label_DSHeight.Text = num2str(changingValue);
            aperture(app);
            diffraction(app);
        end

        % Value changing function: Slider_DSWidth
        function Slider_DSWidthValueChanging(app, event)
            changingValue = round(event.Value);
            app.Label_DSWidth.Text = num2str(changingValue);
            aperture(app);
            diffraction(app);
        end

        % Value changing function: Slider_DSSeparation
        function Slider_DSSeparationValueChanging(app, event)
            changingValue = round(event.Value);
            app.Label_DSSeparation.Text = num2str(changingValue);
            aperture(app);
            diffraction(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FraunhoferDiffractionUIFigure = uifigure('Visible', 'off');
            app.FraunhoferDiffractionUIFigure.Name = 'Fraunhofer Diffraction';
            app.FRAUNHOFERDIFFRACTIONATAPERTURESLabel = uilabel(app.FraunhoferDiffractionUIFigure);
            app.FRAUNHOFERDIFFRACTIONATAPERTURESLabel.Text = 'FRAUNHOFER DIFFRACTION AT APERTURES';
            app.TabGroup = uitabgroup(app.FraunhoferDiffractionUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab_Circular = uitab(app.TabGroup);
            app.Tab_Circular.Title = 'Circle';
            app.Label_Radius = uilabel(app.Tab_Circular);
            app.Label_Radius.Text = '15';
            app.RadiusSliderLabel = uilabel(app.Tab_Circular);
            app.RadiusSliderLabel.Text = 'Radius*';
            app.Slider_Radius = uislider(app.Tab_Circular);
            app.Slider_Radius.Limits = [0 50];
            app.Slider_Radius.ValueChangedFcn = createCallbackFcn(app, @Slider_RadiusValueChanged, true);
            app.Slider_Radius.ValueChangingFcn = createCallbackFcn(app, @Slider_RadiusValueChanging, true);
            app.Slider_Radius.Value = 15;
            app.Label_Radius_unit = uilabel(app.Tab_Circular);
            app.Label_Radius_unit.Text = '586';
            app.Label_microns = uilabel(app.Tab_Circular);
            app.Label_microns.Text = 'microns**';
            app.Label_D = uilabel(app.Tab_Circular);
            app.Label_D.Text = 'D ≈';
            app.Tab_Rectangular = uitab(app.TabGroup);
            app.Tab_Rectangular.Title = 'Rect. ';
            app.HeightSliderLabel = uilabel(app.Tab_Rectangular);
            app.HeightSliderLabel.Text = 'Height*';
            app.Slider_Height = uislider(app.Tab_Rectangular);
            app.Slider_Height.Limits = [0 40];
            app.Slider_Height.ValueChangedFcn = createCallbackFcn(app, @Slider_HeightValueChanged, true);
            app.Slider_Height.ValueChangingFcn = createCallbackFcn(app, @Slider_HeightValueChanging, true);
            app.Slider_Height.Value = 20;
            app.WidthSliderLabel = uilabel(app.Tab_Rectangular);
            app.WidthSliderLabel.Text = 'Width*';
            app.Slider_Width = uislider(app.Tab_Rectangular);
            app.Slider_Width.Limits = [0 40];
            app.Slider_Width.ValueChangedFcn = createCallbackFcn(app, @Slider_WidthValueChanged, true);
            app.Slider_Width.ValueChangingFcn = createCallbackFcn(app, @Slider_WidthValueChanging, true);
            app.Slider_Width.Value = 10;
            app.Label_Height = uilabel(app.Tab_Rectangular);
            app.Label_Height.Text = '20';
            app.Label_Width = uilabel(app.Tab_Rectangular);
            app.Label_Width.Text = '10';
            app.Tab_DoubleSlit = uitab(app.TabGroup);
            app.Tab_DoubleSlit.Title = 'Slits ';
            app.Label_DSHeight = uilabel(app.Tab_DoubleSlit);
            app.Label_DSHeight.Text = '15';
            app.Label_DSWidth = uilabel(app.Tab_DoubleSlit);
            app.Label_DSWidth.Text = '3';
            app.Label_DSSeparation = uilabel(app.Tab_DoubleSlit);
            app.Label_DSSeparation.Text = '30';
            app.HeightSliderLabel_2 = uilabel(app.Tab_DoubleSlit);
            app.HeightSliderLabel_2.Text = 'Height*';
            app.Slider_DSHeight = uislider(app.Tab_DoubleSlit);
            app.Slider_DSHeight.Limits = [10 50];
            app.Slider_DSHeight.ValueChangingFcn = createCallbackFcn(app, @Slider_DSHeightValueChanging, true);
            app.Slider_DSHeight.Value = 15;
            app.WidthSliderLabel_2 = uilabel(app.Tab_DoubleSlit);
            app.WidthSliderLabel_2.Text = 'Width*';
            app.Slider_DSWidth = uislider(app.Tab_DoubleSlit);
            app.Slider_DSWidth.Limits = [1 11];
            app.Slider_DSWidth.ValueChangingFcn = createCallbackFcn(app, @Slider_DSWidthValueChanging, true);
            app.Slider_DSWidth.Value = 3;
            app.SeparationLabel = uilabel(app.Tab_DoubleSlit);
            app.SeparationLabel.Text = 'Separation*';
            app.Slider_DSSeparation = uislider(app.Tab_DoubleSlit);
            app.Slider_DSSeparation.Limits = [15 100];
            app.Slider_DSSeparation.ValueChangingFcn = createCallbackFcn(app, @Slider_DSSeparationValueChanging, true);
            app.Slider_DSSeparation.Value = 30;
            app.AperturetypeLabel = uilabel(app.FraunhoferDiffractionUIFigure);
            app.AperturetypeLabel.Text = 'Aperture type:';
            app.nmSliderLabel = uilabel(app.FraunhoferDiffractionUIFigure);
            app.nmSliderLabel.Text = 'λ [nm]';
            app.Slider_Lambda = uislider(app.FraunhoferDiffractionUIFigure);
            app.Slider_Lambda.Limits = [400 700];
            app.Slider_Lambda.ValueChangedFcn = createCallbackFcn(app, @Slider_LambdaValueChanged, true);
            app.Slider_Lambda.ValueChangingFcn = createCallbackFcn(app, @Slider_LambdaValueChanging, true);
            app.Slider_Lambda.Value = 650;
            app.zcmSliderLabel = uilabel(app.FraunhoferDiffractionUIFigure);
            app.zcmSliderLabel.Text = 'z [cm]';
            app.Slider_Z = uislider(app.FraunhoferDiffractionUIFigure);
            app.Slider_Z.Limits = [50 120];
            app.Slider_Z.ValueChangedFcn = createCallbackFcn(app, @Slider_ZValueChanged, true);
            app.Slider_Z.ValueChangingFcn = createCallbackFcn(app, @Slider_ZValueChanging, true);
            app.Slider_Z.Value = 80;
            app.Label_Lambda = uilabel(app.FraunhoferDiffractionUIFigure);
            app.Label_Lambda.Text = '650';
            app.Label_Z = uilabel(app.FraunhoferDiffractionUIFigure);
            app.Label_Z.Text = '80';
            app.InpixelsLabel = uilabel(app.FraunhoferDiffractionUIFigure);
            app.InpixelsLabel.Text = '*In pixels.';
            app.Panel_Aperture = uipanel(app.FraunhoferDiffractionUIFigure);
            app.Panel_Aperture.Title = 'Aperture';
            app.UIAxes_Aperture = uiaxes(app.Panel_Aperture);
            title(app.UIAxes_Aperture, '')
            xlabel(app.UIAxes_Aperture, '')
            ylabel(app.UIAxes_Aperture, '')
            app.UIAxes_Aperture.XLim = [0 256];
            app.UIAxes_Aperture.YLim = [0 256];
            app.UIAxes_Aperture.XTick = [0 1024];
            app.UIAxes_Aperture.XTickLabel = '';
            app.UIAxes_Aperture.YTick = [0 1024];
            app.UIAxes_Aperture.YTickLabel = '';
            app.Panel_Diffraction = uipanel(app.FraunhoferDiffractionUIFigure);
            app.Panel_Diffraction.Title = 'Diffraction';
            app.UIAxes_Diffraction = uiaxes(app.Panel_Diffraction);
            title(app.UIAxes_Diffraction, '')
            xlabel(app.UIAxes_Diffraction, '')
            ylabel(app.UIAxes_Diffraction, '')
            app.UIAxes_Diffraction.XLim = [0 256];
            app.UIAxes_Diffraction.YLim = [0 256];
            app.UIAxes_Diffraction.XTick = [0 1024];
            app.UIAxes_Diffraction.XTickLabel = '';
            app.UIAxes_Diffraction.YTick = [0 1024];
            app.UIAxes_Diffraction.YTickLabel = '';
            app.Panel_DiffractionCS = uipanel(app.FraunhoferDiffractionUIFigure);
            app.Panel_DiffractionCS.Title = 'Diffraction''s Cross-section';
            app.UIAxes_DiffractionCS = uiaxes(app.Panel_DiffractionCS);
            title(app.UIAxes_DiffractionCS, '')
            xlabel(app.UIAxes_DiffractionCS, '')
            ylabel(app.UIAxes_DiffractionCS, '')
            app.UIAxes_DiffractionCS.XLim = [0 256];
            app.UIAxes_DiffractionCS.YLim = [0 256];
            app.UIAxes_DiffractionCS.XTick = [0 1024];
            app.UIAxes_DiffractionCS.XTickLabel = '';
            app.UIAxes_DiffractionCS.YTick = [0 1024];
            app.UIAxes_DiffractionCS.YTickLabel = '';
            app.NumberofsamplingpointsDropDownLabel = uilabel(app.FraunhoferDiffractionUIFigure);
            app.NumberofsamplingpointsDropDownLabel.Text = 'Number of sampling points:';
            app.DropDown_SP = uidropdown(app.FraunhoferDiffractionUIFigure);
            app.DropDown_SP.Items = {'256', '512', '1024'};
            app.DropDown_SP.ValueChangedFcn = createCallbackFcn(app, @DropDown_SPValueChanged, true);
            app.DropDown_SP.Value = '512';
            app.Image = uiimage(app.FraunhoferDiffractionUIFigure);
            app.Image.ImageSource = 'cvut_logo.jpg';
            app.Image2 = uiimage(app.FraunhoferDiffractionUIFigure);
            app.Image2.ImageSource = 'LightSpectrum.jpg';
            app.Diameterapproximationconsideringapertureplane1x1cmLabel = uilabel(app.FraunhoferDiffractionUIFigure);
            app.Diameterapproximationconsideringapertureplane1x1cmLabel.Text = '**Diameter approximation considering aperture plane = 1x1 cm.';
            % Show the figure after all components are created
            app.FraunhoferDiffractionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FraunhoferDiffraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FraunhoferDiffractionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FraunhoferDiffractionUIFigure)
        end
    end
end